classdef RFSoC_MVDR_Demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RFSoCMVDRDemoUIFigure   matlab.ui.Figure
        SignalofInterestPanel   matlab.ui.container.Panel
        SignalAngleSlider       matlab.ui.control.Slider
        dBLabel                 matlab.ui.control.Label
        SignalGainSpinner       matlab.ui.control.Spinner
        degreesSpinnerLabel     matlab.ui.control.Label
        SignalAngleSpinner      matlab.ui.control.Spinner
        SignalAngleLabel        matlab.ui.control.Label
        SignalGainLabel         matlab.ui.control.Label
        InterferingSignalPanel  matlab.ui.container.Panel
        InterfererAngleSlider   matlab.ui.control.Slider
        dBLabel_2               matlab.ui.control.Label
        InterfererGainSpinner   matlab.ui.control.Spinner
        degreesSpinnerLabel_2   matlab.ui.control.Label
        InterfererAngleSpinner  matlab.ui.control.Spinner
        InterfererAngleLabel    matlab.ui.control.Label
        InterfererGainLabel     matlab.ui.control.Label
        SteeringAngleSlider     matlab.ui.control.Slider
        degreesSpinnerLabel_3   matlab.ui.control.Label
        SteeringAngleSpinner    matlab.ui.control.Spinner
        SteeringAngleLabel      matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        hDemo % RFSoCMVDRDemo object
    end
    
    methods (Access = private)
        
        function updateResponsePlot(app)
            resp = app.hDemo.getArrayResponse();
            plot(app.UIAxes,-90:90,resp);
            xline(app.UIAxes,app.hDemo.SignalAngle,'g');
            xline(app.UIAxes,app.hDemo.InterfererAngle,'r');
            xlim(app.UIAxes,[-90 90]);
            ylim(app.UIAxes,[-60 10]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.hDemo = RFSoCMVDRDemo;
            setSignalAngle(app.hDemo,app.SignalAngleSpinner.Value);
            setSignalGain(app.hDemo,app.SignalGainSpinner.Value);
            setInterfererAngle(app.hDemo,app.InterfererAngleSpinner.Value);
            setInterfererGain(app.hDemo,app.InterfererGainSpinner.Value);
            setSteeringAngle(app.hDemo,app.SteeringAngleSpinner.Value);
            updateResponsePlot(app);
        end

        % Value changed function: SignalAngleSlider
        function SignalAngleSliderValueChanged(app, event)
            value = app.SignalAngleSlider.Value;
            app.SignalAngleSpinner.Value = value;
            setSignalAngle(app.hDemo,value);
            updateResponsePlot(app);
            run(app.hDemo);
        end

        % Value changed function: SignalAngleSpinner
        function SignalAngleSpinnerValueChanged(app, event)
            value = app.SignalAngleSpinner.Value;
            app.SignalAngleSlider.Value = value;
            setSignalAngle(app.hDemo,value);
            updateResponsePlot(app);
            run(app.hDemo);
        end

        % Value changed function: SignalGainSpinner
        function SignalGainSpinnerValueChanged(app, event)
            value = app.SignalGainSpinner.Value;
            setSignalGain(app.hDemo,value);
            run(app.hDemo);
        end

        % Value changed function: InterfererGainSpinner
        function InterfererGainSpinnerValueChanged(app, event)
            value = app.InterfererGainSpinner.Value;
            setInterfererGain(app.hDemo,value);
            run(app.hDemo);
        end

        % Value changed function: InterfererAngleSpinner
        function InterfererAngleSpinnerValueChanged(app, event)
            value = app.InterfererAngleSpinner.Value;
            app.InterfererAngleSlider.Value = value;
            setInterfererAngle(app.hDemo,value);
            updateResponsePlot(app);
            run(app.hDemo);
        end

        % Value changed function: InterfererAngleSlider
        function InterfererAngleSliderValueChanged(app, event)
            value = app.InterfererAngleSlider.Value;
            app.InterfererAngleSpinner.Value = value;
            setInterfererAngle(app.hDemo,value);
            updateResponsePlot(app);
            run(app.hDemo);
        end

        % Close request function: RFSoCMVDRDemoUIFigure
        function RFSoCMVDRDemoUIFigureCloseRequest(app, event)
            stop(app.hDemo);
            delete(app);
        end

        % Value changed function: SteeringAngleSpinner
        function SteeringAngleSpinnerValueChanged(app, event)
            value = app.SteeringAngleSpinner.Value;
            app.SteeringAngleSlider.Value = value;
            setSteeringAngle(app.hDemo,value);
            updateResponsePlot(app);
            run(app.hDemo);
        end

        % Value changed function: SteeringAngleSlider
        function SteeringAngleSliderValueChanged(app, event)
            value = app.SteeringAngleSlider.Value;
            app.SteeringAngleSpinner.Value = value;
            setSteeringAngle(app.hDemo,value);
            updateResponsePlot(app);
            run(app.hDemo);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RFSoCMVDRDemoUIFigure = uifigure('Visible', 'off');
            app.RFSoCMVDRDemoUIFigure.Name = 'RFSoC MVDR Demo';
            app.RFSoCMVDRDemoUIFigure.CloseRequestFcn = createCallbackFcn(app, @RFSoCMVDRDemoUIFigureCloseRequest, true);
            app.SignalofInterestPanel = uipanel(app.RFSoCMVDRDemoUIFigure);
            app.SignalofInterestPanel.Title = 'Signal of Interest';
            app.SignalAngleSlider = uislider(app.SignalofInterestPanel);
            app.SignalAngleSlider.Limits = [-90 90];
            app.SignalAngleSlider.ValueChangedFcn = createCallbackFcn(app, @SignalAngleSliderValueChanged, true);
            app.SignalAngleSlider.Value = -45;
            app.dBLabel = uilabel(app.SignalofInterestPanel);
            app.dBLabel.Text = 'dB';
            app.SignalGainSpinner = uispinner(app.SignalofInterestPanel);
            app.SignalGainSpinner.Step = 3;
            app.SignalGainSpinner.Limits = [-Inf 3];
            app.SignalGainSpinner.RoundFractionalValues = 'on';
            app.SignalGainSpinner.ValueChangedFcn = createCallbackFcn(app, @SignalGainSpinnerValueChanged, true);
            app.SignalGainSpinner.Value = -6;
            app.degreesSpinnerLabel = uilabel(app.SignalofInterestPanel);
            app.degreesSpinnerLabel.Text = {'degrees'; ''};
            app.SignalAngleSpinner = uispinner(app.SignalofInterestPanel);
            app.SignalAngleSpinner.Limits = [-90 90];
            app.SignalAngleSpinner.RoundFractionalValues = 'on';
            app.SignalAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @SignalAngleSpinnerValueChanged, true);
            app.SignalAngleSpinner.Value = -45;
            app.SignalAngleLabel = uilabel(app.SignalofInterestPanel);
            app.SignalAngleLabel.Text = 'Angle:';
            app.SignalGainLabel = uilabel(app.SignalofInterestPanel);
            app.SignalGainLabel.Text = 'Gain:';
            app.InterferingSignalPanel = uipanel(app.RFSoCMVDRDemoUIFigure);
            app.InterferingSignalPanel.Title = 'Interfering Signal';
            app.InterfererAngleSlider = uislider(app.InterferingSignalPanel);
            app.InterfererAngleSlider.Limits = [-90 90];
            app.InterfererAngleSlider.ValueChangedFcn = createCallbackFcn(app, @InterfererAngleSliderValueChanged, true);
            app.InterfererAngleSlider.Value = 17;
            app.dBLabel_2 = uilabel(app.InterferingSignalPanel);
            app.dBLabel_2.Text = 'dB';
            app.InterfererGainSpinner = uispinner(app.InterferingSignalPanel);
            app.InterfererGainSpinner.Step = 3;
            app.InterfererGainSpinner.Limits = [-Inf 3];
            app.InterfererGainSpinner.RoundFractionalValues = 'on';
            app.InterfererGainSpinner.ValueChangedFcn = createCallbackFcn(app, @InterfererGainSpinnerValueChanged, true);
            app.InterfererGainSpinner.Value = -6;
            app.degreesSpinnerLabel_2 = uilabel(app.InterferingSignalPanel);
            app.degreesSpinnerLabel_2.Text = {'degrees'; ''};
            app.InterfererAngleSpinner = uispinner(app.InterferingSignalPanel);
            app.InterfererAngleSpinner.Limits = [-90 90];
            app.InterfererAngleSpinner.RoundFractionalValues = 'on';
            app.InterfererAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @InterfererAngleSpinnerValueChanged, true);
            app.InterfererAngleSpinner.Value = 17;
            app.InterfererAngleLabel = uilabel(app.InterferingSignalPanel);
            app.InterfererAngleLabel.Text = 'Angle:';
            app.InterfererGainLabel = uilabel(app.InterferingSignalPanel);
            app.InterfererGainLabel.Text = 'Gain:';
            app.SteeringAngleSlider = uislider(app.RFSoCMVDRDemoUIFigure);
            app.SteeringAngleSlider.Limits = [-90 90];
            app.SteeringAngleSlider.MajorTicks = [-90 -75 -60 -45 -30 -15 0 15 30 45 60 75 90];
            app.SteeringAngleSlider.ValueChangedFcn = createCallbackFcn(app, @SteeringAngleSliderValueChanged, true);
            app.SteeringAngleSlider.Value = -45;
            app.degreesSpinnerLabel_3 = uilabel(app.RFSoCMVDRDemoUIFigure);
            app.degreesSpinnerLabel_3.Text = 'degrees';
            app.SteeringAngleSpinner = uispinner(app.RFSoCMVDRDemoUIFigure);
            app.SteeringAngleSpinner.Limits = [-90 90];
            app.SteeringAngleSpinner.RoundFractionalValues = 'on';
            app.SteeringAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @SteeringAngleSpinnerValueChanged, true);
            app.SteeringAngleSpinner.Value = -45;
            app.SteeringAngleLabel = uilabel(app.RFSoCMVDRDemoUIFigure);
            app.SteeringAngleLabel.Text = 'Steering Angle:';
            app.UIAxes = uiaxes(app.RFSoCMVDRDemoUIFigure);
            title(app.UIAxes, 'Beamforming Response')
            xlabel(app.UIAxes, 'Azimuth Angle (degrees)')
            ylabel(app.UIAxes, 'Normalized Power (dB)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.RFSoCMVDRDemoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RFSoC_MVDR_Demo

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RFSoCMVDRDemoUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.RFSoCMVDRDemoUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RFSoCMVDRDemoUIFigure)
        end
    end
end